classdef DAST < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                        matlab.ui.Figure
        Panel                           matlab.ui.container.Panel
        UploadedWorkspaceEditField      matlab.ui.control.EditField
        LoadPresetButton                matlab.ui.control.Button
        AudioSettingsTabs               matlab.ui.container.TabGroup
        QuickstartTab                   matlab.ui.container.Tab
        dBLabel                         matlab.ui.control.Label
        SNRvalue                        matlab.ui.control.Label
        SNRLabel                        matlab.ui.control.Label
        MaskerlevelEditField            matlab.ui.control.NumericEditField
        MaskerlevelEditFieldLabel       matlab.ui.control.Label
        TargetlevelEditField            matlab.ui.control.NumericEditField
        TargetlevelLabel                matlab.ui.control.Label
        RandomizewithinlistCheckBox     matlab.ui.control.CheckBox
        MaskertypeDropDown              matlab.ui.control.DropDown
        MaskertypeDropDownLabel         matlab.ui.control.Label
        ListnumberDropDown              matlab.ui.control.DropDown
        ListnumberDropDownLabel         matlab.ui.control.Label
        TalkerDropDown                  matlab.ui.control.DropDown
        TalkerDropDownLabel             matlab.ui.control.Label
        dBSPLLabel_2                    matlab.ui.control.Label
        dBSPLLabel                      matlab.ui.control.Label
        AdvancedTab                     matlab.ui.container.Tab
        StartinglistDropDown            matlab.ui.control.DropDown
        StartinglistDropDownLabel       matlab.ui.control.Label
        UploadedCSVCheckBox             matlab.ui.control.CheckBox
        AdvancedOptionInstructionsField  matlab.ui.control.Label
        CSVfileEditField                matlab.ui.control.EditField
        CSVfileEditFieldLabel           matlab.ui.control.Label
        UploadButton                    matlab.ui.control.Button
        TabGroup                        matlab.ui.container.TabGroup
        TestTab                         matlab.ui.container.Tab
        W12Button                       matlab.ui.control.StateButton
        NextlistButton                  matlab.ui.control.Button
        W11Button                       matlab.ui.control.StateButton
        W10Button                       matlab.ui.control.StateButton
        W9Button                        matlab.ui.control.StateButton
        W8Button                        matlab.ui.control.StateButton
        W7Button                        matlab.ui.control.StateButton
        W6Button                        matlab.ui.control.StateButton
        W5Button                        matlab.ui.control.StateButton
        W4Button                        matlab.ui.control.StateButton
        W3Button                        matlab.ui.control.StateButton
        W2Button                        matlab.ui.control.StateButton
        W1Button                        matlab.ui.control.StateButton
        PauseButton                     matlab.ui.control.StateButton
        MarkallButton                   matlab.ui.control.StateButton
        Instructions                    matlab.ui.control.Label
        SentencesLabel                  matlab.ui.control.Label
        currentSent                     matlab.ui.control.Label
        currentList                     matlab.ui.control.Label
        ContinueButton                  matlab.ui.control.Button
        DanskStningstestLabel           matlab.ui.control.Label
        StopButton                      matlab.ui.control.Button
        StartButton                     matlab.ui.control.Button
        SettingsTab                     matlab.ui.container.Tab
        SettingsPanelinSettingsTab      matlab.ui.container.Panel
        ApplySettingsButton             matlab.ui.control.Button
        SavePresetButton                matlab.ui.control.Button
        MaskersettingsButtonGroup       matlab.ui.container.ButtonGroup
        DurationbeforeEditField         matlab.ui.control.NumericEditField
        DurationbeforeEditFieldLabel    matlab.ui.control.Label
        SecLabel                        matlab.ui.control.Label
        IntermittentButton              matlab.ui.control.RadioButton
        ContinuousButton                matlab.ui.control.RadioButton
        CalibrationButtonGroup          matlab.ui.container.ButtonGroup
        LeftMeasuredEditFieldLabel      matlab.ui.control.Label
        LeftMeasuredEditField           matlab.ui.control.NumericEditField
        RightMeasuredEditField          matlab.ui.control.NumericEditField
        RightMeasuredEditFieldLabel     matlab.ui.control.Label
        ImportequalizationfilterButton  matlab.ui.control.Button
        FilterEditField                 matlab.ui.control.EditField
        FilterEditFieldLabel            matlab.ui.control.Label
        PlaycalibrationfileButton       matlab.ui.control.StateButton
        MeasuredLabel                   matlab.ui.control.Label
        dBZLabel                        matlab.ui.control.Label
        PlaybackmodeButtonGroup         matlab.ui.container.ButtonGroup
        MultiButton                     matlab.ui.control.RadioButton
        MonoButton                      matlab.ui.control.RadioButton
        StereoButton                    matlab.ui.control.RadioButton
        PlaybackmethodButtonGroup       matlab.ui.container.ButtonGroup
        ChannelsInEditField             matlab.ui.control.EditField
        ChannelsInEditFieldLabel        matlab.ui.control.Label
        BasicButton                     matlab.ui.control.RadioButton
        AudioToolboxButton              matlab.ui.control.RadioButton
        PlayrecButton                   matlab.ui.control.RadioButton
        DriverDropDownLabel             matlab.ui.control.Label
        DriverDropDown                  matlab.ui.control.DropDown
        ChannelsOutEditField            matlab.ui.control.EditField
        ChannelsOutEditFieldLabel       matlab.ui.control.Label
        ScoringtypeButtonGroup          matlab.ui.container.ButtonGroup
        WordByWordButton                matlab.ui.control.RadioButton
        KeywordBasedButton              matlab.ui.control.RadioButton
        PresentationmodeButtonGroup     matlab.ui.container.ButtonGroup
        SelectVideoDirButton            matlab.ui.control.Button
        currentVideoDir                 matlab.ui.control.EditField
        PathtofilesLabel_2              matlab.ui.control.Label
        SelectAudioDirButton            matlab.ui.control.Button
        currentAudioDir                 matlab.ui.control.EditField
        PathtofilesLabel                matlab.ui.control.Label
        AudioVisualPresentationButton   matlab.ui.control.RadioButton
        AudioPresentationButton         matlab.ui.control.RadioButton
        ResultTypeButtonGroup           matlab.ui.container.ButtonGroup
        SpeechReceptionThresholdButton  matlab.ui.control.RadioButton
        PercentageCorrectButton         matlab.ui.control.RadioButton
        BasicinformationPanel           matlab.ui.container.Panel
        FilenameEditField               matlab.ui.control.EditField
        FilenameEditFieldLabel          matlab.ui.control.Label
        ParticipantIDEditField          matlab.ui.control.EditField
        ParticipantIDEditFieldLabel     matlab.ui.control.Label
        TesterIDEditField               matlab.ui.control.EditField
        TesterIDEditFieldLabel          matlab.ui.control.Label
    end


    properties (Access = public)

        % % Definition of global variables

        % Main test variable (contains info, setup, results)
        data

        % Containing information of current test iteration
        tmp

    end

    % Functions that require data outside of the app
    methods (Access = public)

        function loadMixAndPlaySentence(app)

            % --- Pseudocode --- TODO Implement this!!!
            % if adaptive, calculate SNR
            % if cont and nothing is currently playing, loadAndPlayContinuousMasker
                % load masker
                % apply equalization filter
                % scale to correct SPL
            % if noncont, loadIntermittentMasker
                % load masker
                % apply equalization filter
                % scale to correct SPL
            % load target, loadTarget
                % load masker
                % apply equalization filter
                % scale to correct SPL
            % play A or AV target (and if noncont, masker) via playSignal
            % record response, if relevant
            % if cont and at end of list, stop masker
            % -------------------

            % Update target and masker levels, if relevant
            if app.SpeechReceptionThresholdButton.Value == 1
                updateLevels(app);
            end

            % Start continuous masker, if relevant
            if app.ContinuousButton.Value == 1
                loadAndPlayContinuousMasker(app);
            end

            % Read the target file
            loadTarget(app);

            % Load intermittent masker, if relevant
            if app.IntermittentButton.Value == 1
                loadIntermittentMasker(app);
            end

            % Combine target and masker (TODO)
            mixtureSignal = targetSignal + maskerSignal;

            % Play the stimuli
            playSignal(app,mixtureSignal);

            % Record via Audio Toolbox, if relevant
            if ~isempty(app.data.Setup.playbackChannelsIn)
                recordViaAudioToolbox(app);
            end

            % Enabling continue button after sentence is done
            if app.PauseButton.Value == 1 || app.StopButton.Enable == "off" % if pause activated or end of test
                app.ContinueButton.Enable = "off"; 
            else
                app.ContinueButton.Enable = "on";
            end

        end

        function updateLevels(app)
            % TODO Add option to adjust masker level instead of target level

            % Update tmp values
            app.tmp.SNRvalue = randi([-10 10]); % TODO Incorporate clever method here
            app.tmp.targetLevel = app.tmp.maskerLevel-app.tmp.SNRvalue;

            % Update values stored in the Setup struct, which control the user interface
            app.data.Setup.targetLevel = app.tmp.targetLevel;
            app.data.Setup.SNRvalue = app.tmp.SNRvalue;

            % Update the user interface
            app.TargetlevelEditField.Value = app.data.Setup.targetLevel;
            app.SNRvalue.Text = sprintf('%1.1f',app.data.Setup.SNRvalue);

        end

        function loadAndPlayContinuousMasker(app) % >>> TODO <<<

            % if non-blocking method, check for active sound and play if nothing
            % if blocking method, check for active sound and initialize if nothing

            % Read info about masker
            % Read in random segment of masker
            % Add ramping to masker

            % Format, equalization, and scale (TODO double check!)
            % app.tmp.maskerSignal = replicateSignalAcrossChannels(app,app.tmp.maskerSignal);
            % app.tmp.maskerSignal = applyEqualizationFilter(app,app.tmp.maskerSignal);
            % app.tmp.maskerSignal = scaleToDesiredLevel(app,app.tmp.maskerSignal);

        end

        function loadIntermittentMasker(app)

            % Read info about masker
            app.tmp.maskerInfo = audioinfo(app.tmp.maskerFullfile);
            
            % Read in random segment of masker
            app.tmp.maskerRandSample = round((maskerInfo.TotalSamples-length(app.tmp.targetSignal))*rand); % TODO Save in data?
            app.tmp.maskerSignal = audioread(app.tmp.maskerFullfile,...
                [app.tmp.maskerRandSample, app.tmp.maskerRandSample+length(app.tmp.targetSignal)-1]);
            
            % Add ramping to masker
            app.tmp.maskerSignal(1:app.data.Setup.hannLen) = app.data.Setup.hannWindow(1:app.data.Setup.hannLen).*app.tmp.maskerSignal(1:app.data.Setup.hannLen);
            app.tmp.maskerSignal(end-app.data.Setup.hannLen+1:end) = app.data.Setup.hannWindow(app.data.Setup.hannLen+1:end).*app.tmp.maskerSignal(end-app.data.Setup.hannLen+1:end);

            % Format, equalization, and scale (TODO double check!)
            app.tmp.maskerSignal = replicateSignalAcrossChannels(app,app.tmp.maskerSignal);
            app.tmp.maskerSignal = applyEqualizationFilter(app,app.tmp.maskerSignal);
            app.tmp.maskerSignal = scaleToDesiredLevel(app,app.tmp.maskerSignal);

        end

        function loadTarget(app)

            % Read the target file
            app.tmp.targetSignal = audioread(app.tmp.targetFullfile);

            % Adjust padding if necessary
            if app.data.Setup.DurationBefore > 0.5

                app.tmp.targetSignal = [zeros((app.data.Setup.DurationBefore-0.5)*app.data.Setup.Fs,1); ...
                    app.tmp.targetSignal(:)];

            end

            % Format, equalization, and scale
            app.tmp.targetSignal = replicateSignalAcrossChannels(app,app.tmp.targetSignal);
            app.tmp.targetSignal = applyEqualizationFilter(app,app.tmp.targetSignal);
            app.tmp.targetSignal = scaleToDesiredLevel(app,app.tmp.targetSignal);

        end

        function signal = replicateSignalAcrossChannels(app,signal)

            % Replicate vectors as needed
            switch app.data.Setup.playbackMode
                case 'Mono'
                    % do nothing
                case 'Stereo'
                    signal = repmat(signal,1,2);
                case 'Multi'
                    % signal = repmat(signal,1,TODO);
            end

        end

        function signal = applyEqualizationFilter(app,signal)

            % Apply equalization filter, if relevant
            if ~isempty(app.data.Setup.equalizationFilter)
                for cc = 1:size(app.data.Setup.equalizationFilter,2)
                    app.tmp.targetSignal(:,cc) = filter(app.data.Setup.equalizationFilter(:,cc),1,app.tmp.targetSignal(:,cc));
                    app.tmp.maskerSignal(:,cc) = filter(app.data.Setup.equalizationFilter(:,cc),1,app.tmp.maskerSignal(:,cc));
                end
            end

        end

        function signal = scaleToDesiredLevel(app,signal)

%             % Scale the digital levels in each channel to get the desired physical level
%             for cc = 1:size(targetSignal,2)
%                 targetGain = (app.tmp.targetLevel-app.data.Setup.dBSPLRef) + (app.data.Setup.dBSPLRef-app.data.Setup.calibrationMeasuredLevel(cc)); % calculate gain (i.e., sentence correction + calibration correction)
%                 targetSignal(:,cc) = targetSignal(:,cc)*(10^(targetGain/20));
%             end
%             for cc = 1:size(maskerSignal,2) % NOTE assumes maskerFullfile has an long-term rms scaled to -26 dB FS to match the DAST material
%                 maskerGain = (app.tmp.maskerLevel-app.data.Setup.dBSPLRef) + (app.data.Setup.dBSPLRef-app.data.Setup.calibrationMeasuredLevel(cc));
%                 maskerSignal(:,cc) = maskerSignal(:,cc)*(10^(maskerGain/20));
%             end

        end

        function updateInformation(app)

            app.tmp.iteration = app.tmp.iteration + 1; % current sentence
            app.tmp.whichLine = app.tmp.whichLine + 1; % for saving results

            if app.AudioSettingsTabs.SelectedTab == app.QuickstartTab

                % TODO what happens when mixed talkers/mixed lists are
                % chosen.

            elseif app.AudioSettingsTabs.SelectedTab == app.AdvancedTab

                % Get current sentence number and text (from list)
                app.tmp.listNum = app.tmp.lists(app.tmp.list_iteration);
                app.tmp.sentences = app.tmp.sentencesFull(app.tmp.sentencesFull.List == app.tmp.listNum,:);

                % Get list/sentence individual settings
                app.tmp.talker = char(app.tmp.sentences.Talker(app.tmp.iteration)); % current talker
                app.tmp.maskerType = char(app.tmp.sentences.Masker(app.tmp.iteration)); % current masker

                % File locations
                app.tmp.targetDir = fullfile(app.data.Setup.Audiopath,'Speech',app.tmp.talker); % target dir
                app.tmp.maskerDir = fullfile(app.data.Setup.Audiopath,'Noise'); % masker dir
                if app.AudioVisualPresentationButton.Value == 1
                    app.tmp.videoDir = fullfile(app.data.Setup.Videopath,app.tmp.talker); % video dir
                end

                % Grab levels
                app.tmp.targetLevel = app.tmp.sentences.("Target level")(app.tmp.iteration); % current target level
                app.tmp.maskerLevel = app.tmp.sentences.("Masker level")(app.tmp.iteration); % current masker level

            end

            % Get current sentence number and text
            app.tmp.sent = app.tmp.sentences.Number(app.tmp.iteration); % current sentence
            app.tmp.sentText = app.tmp.sentences.Sentence(app.tmp.iteration); % current text sentence
            app.tmp.sentTot = height(app.tmp.sentences); % total num of sentences per list

            % Update fields
            app.currentSent.Text = sprintf('Sentence %04d (%i/%i)', app.tmp.sent, app.tmp.iteration, app.tmp.sentTot);
            app.currentList.Text = sprintf('List %i (%i/%i)', app.tmp.listNum, app.tmp.list_iteration, app.tmp.listTot);

            % ----------------------------------------------------

            % Buttons: keywords or word-based
            buttonSz = 8; % initial button size (wo. words)
            letterSz = 12; % increment for each letter in word
            wordSpacing = 4; % spacing between buttons (words)

            % make all buttons invisible
            for button = 1:app.tmp.totButtons
                app.(sprintf('W%iButton', button)).Visible = "off";
            end

            str = string(app.tmp.sentences.Sentence(app.tmp.iteration));
            Words = strsplit(str);
            nWords = numel(Words);
            nLetters = strlength(str);
            app.tmp.Keywords = string(app.tmp.sentences.Keywords(app.tmp.iteration));
            % divide 3 keywords and remove possible spaces in between words
            app.tmp.Keywords = strtrim(strsplit(string(app.tmp.Keywords), ', '));

            % initializing initial button position
            centerGrid = app.SentencesLabel.Position(1)+ app.SentencesLabel.Position(3)/2;
            totalSentWidth = buttonSz + wordSpacing + nLetters*(letterSz);
            initPosition = centerGrid - totalSentWidth/2;
            totalSize = initPosition;

            for i = 1:nWords

                % --- displaying each word onto buttons
                app.(sprintf('W%iButton', i)).Visible = "on";
                app.(sprintf('W%iButton', i)).Text = Words(i);

                % Keywords: find in which button and bold text + saving
                if any(strcmpi(Words(i),app.tmp.Keywords))
                    app.(sprintf('W%iButton', i)).FontWeight = 'bold';
                    app.tmp.KeywordsButtons = [app.tmp.KeywordsButtons i];
                end

                % changing width (Pos 3) ---
                app.(sprintf('W%iButton', i)).Position(3) = buttonSz+(letterSz*strlength(Words(i)));

                % --- spacing and locating each button (Pos 1) ---
                app.(sprintf('W%iButton', i)).Position(1) = totalSize;
                % current size + width of last button + space between words
                totalSize = totalSize + app.(sprintf('W%iButton', i)).Position(3) + wordSpacing;

            end

            % Disable non-keyword buttons (when keyword-based scoring)
            if app.data.Setup.scoringType == "Keyword-based"
                for m = 1:app.tmp.totButtons
                    if ~any(app.tmp.KeywordsButtons == m)
                        app.(sprintf('W%iButton', m)).Enable = "off";
                    end
                end
            end

            % ----------------------------------------------------

            % Target and masker
            app.tmp.targetFile = sprintf('%04d.wav', app.tmp.sent);
            app.tmp.maskerFile = strcat(app.tmp.maskerType, '.wav');
            app.tmp.targetFullfile = fullfile(app.tmp.targetDir, app.tmp.targetFile);
            app.tmp.maskerFullfile = fullfile(app.tmp.maskerDir, app.tmp.maskerFile);
            if app.AudioVisualPresentationButton.Value == 1
                app.tmp.videoFile = sprintf('%04d.mp4', app.tmp.sent);
                app.tmp.videoFullfile = fullfile(app.tmp.videoDir, app.tmp.videoFile);
            end

        end


        function recordViaAudioToolbox(app)

            % Prepare for recording, if relevant
            app.tmp.playbackObjectFileWriter = dsp.AudioFileWriter(...
                fullfile(app.tmp.resultRecordingsDir,app.tmp.targetFile),...
                'FileFormat','WAV',...
                'SampleRate',app.data.Setup.Fs);

            % Enable continue button after end of sentence
            app.ContinueButton.Enable = "on";

            % Record the response, if relevant
            pause(0);
            tic;
            recordingMaxSec = 60;
            app.tmp.recordingFlag = true;
            while app.tmp.recordingFlag==true && toc < recordingMaxSec
                signalRecorded = app.data.Setup.playbackObjectRecorder();
                app.tmp.playbackObjectFileWriter(signalRecorded);
                drawnow; % update process for pending callbacks
            end

            % release audio objects
            releaseAudioObjs(app)

        end

    end

    % Functions that require data inside of the app
    methods (Access = private)

        function disableButtons_initialState(app)

            % User input enabled
            app.StartButton.Enable = "on";
            app.BasicinformationPanel.Enable = "on";
            app.Panel.Enable = "on";
            app.SettingsPanelinSettingsTab.Enable = "on";

            % Test tab disabled
            app.PauseButton.Enable = "off";
            app.StopButton.Enable = "off";
            app.NextlistButton.Enable = "off";
            app.MarkallButton.Enable = "off";

            app.tmp.KeywordsButtons =[];
            app.MarkallButton.Value = 0;
            app.PauseButton.Value = 0;

            for i = 1:app.tmp.totButtons
                app.(sprintf('W%iButton', i)).Visible = "off";
                app.(sprintf('W%iButton', i)).Value = 0;
                app.(sprintf('W%iButton', i)).FontWeight = 'normal';
            end

            app.MarkallButton.Visible = "off";
            app.NextlistButton.Visible = "off";
            app.SentencesLabel.Visible = "off";
            app.Instructions.Visible = "off";
            app.currentList.Visible = "off";
            app.currentSent.Visible = "off";

            app.ContinueButton.Enable = "off";

        end

        function enableButtons_testState(app)

            % User input disabled
            app.StartButton.Enable = "off";
            app.BasicinformationPanel.Enable = "off";
            app.Panel.Enable = "off";
            app.SettingsPanelinSettingsTab.Enable = "off";

            % Test tab enabled
            app.PauseButton.Enable = "on";
            app.StopButton.Enable = "on";
            app.MarkallButton.Enable = "on";

            for i = 1:app.tmp.totButtons
                app.(sprintf('W%iButton', i)).Visible = "on";
                app.(sprintf('W%iButton', i)).Enable = "on";

            end
            app.MarkallButton.Visible = "on";
            app.NextlistButton.Visible = "off";
            app.SentencesLabel.Visible = "on";
            app.SentencesLabel.Text = 'Sentence';
            app.SentencesLabel.FontSize = 14;
            app.Instructions.Visible = "on";
            app.currentList.Visible = "on";
            app.currentSent.Visible = "on";

        end

        function releaseAudioObjs(app)

            % Avoid crashing by releasing all existing audio objects
            clear sound

            if app.tmp.recordingFlag == true
                app.tmp.recordingFlag = false;
            end
            if app.tmp.playingFlag  == true
                app.tmp.playingFlag = false;
            end
            if ~isempty(app.data.Setup.playbackObjectPlayer)
                release(app.data.Setup.playbackObjectPlayer);
            end
            if ~isempty(app.data.Setup.playbackObjectRecorder)
                release(app.data.Setup.playbackObjectRecorder);
            end
            if ~isempty(app.tmp.playbackObjectFileWriter)
                release(app.tmp.playbackObjectFileWriter);
            end

        end

        function getPlaybackSettings(app)

            % function called by getWorkpaceSettings PlayCalibrationFile

            % ------------------------------------------------------------
            % Playback settings

            if app.MonoButton.Value == 1; app.data.Setup.playbackMode = 'Mono';
            elseif app.StereoButton.Value == 1; app.data.Setup.playbackMode = 'Stereo';
            else; app.data.Setup.playbackMode = 'Multi';
            end

            % Set up audio driver, etc.
            if app.BasicButton.Value == 1
                app.data.Setup.playbackMethod = 'Basic';
                app.data.Setup.playbackDriver = '';
                app.data.Setup.playbackObjectPlayer = [];
                app.data.Setup.playbackObjectRecorder = [];
                app.data.Setup.playbackChannelsOut = [];
                app.data.Setup.playbackChannelsIn = [];

            elseif app.AudioToolboxButton.Value == 1
                app.data.Setup.playbackMethod = 'AudioToolbox';
                app.data.Setup.playbackDriver = app.DriverDropDown.Value;
                app.data.Setup.playbackChannelsOut = str2num(string(app.ChannelsOutEditField.Value)); %#ok<ST2NM>
                app.data.Setup.playbackChannelsIn = str2num(string(app.ChannelsInEditField.Value)); %#ok<ST2NM>

                % Make subfolder for recordings, if relevant and when test started
                if app.StartButton.Enable == "off"
                    app.tmp.resultRecordingsDir = fullfile(app.tmp.resultDir,app.tmp.resultName);
                    if ~isempty(app.data.Setup.playbackChannelsIn) && ~exist(app.tmp.resultRecordingsDir, 'dir')
                        mkdir(app.tmp.resultRecordingsDir);
                    end
                end
            else
                app.data.Setup.playbackMethod = 'Playrec';
                app.data.Setup.playbackDriver = 0; % TODO Convert to app.DriverDropDown.Value
                app.data.Setup.playbackChannelsOut = 3:6; % TODO Convert to str2num(string(app.ChannelsOutEditField.Value)); %#ok<ST2NM>
                app.data.Setup.playbackChannelsIn = []; % TODO Convert to str2num(string(app.ChannelsInEditField.Value)); %#ok<ST2NM>
            end

            % ------------------------------------------------------------
            % Setting up playback method
            switch app.data.Setup.playbackMethod

                case 'Basic'

                    % No setup needed

                case 'AudioToolbox' % Matlab Audio Toolbox

                    % Check if dsp object already exists
                    if ~isempty(app.data.Setup.playbackObjectPlayer)
                        release(app.data.Setup.playbackObjectPlayer);
                    end
                    if ~isempty(app.data.Setup.playbackObjectRecorder)
                        release(app.data.Setup.playbackObjectRecorder);
                    end

                    % Create the handle to the device writer
                    if ~isempty(app.data.Setup.playbackChannelsOut)
                        app.data.Setup.playbackObjectPlayer = audioDeviceWriter(app.data.Setup.Fs,...
                            'Driver',app.data.Setup.playbackDriver,...
                            'ChannelMappingSource','Property',...
                            'ChannelMapping',app.data.Setup.playbackChannelsOut,...
                            'BufferSize',app.data.Setup.playbackBuffer);
                    end

                    % Create the handle to the device recorder, if relevant
                    if ~isempty(app.data.Setup.playbackChannelsIn)
                        app.data.Setup.playbackObjectRecorder = audioDeviceReader(app.data.Setup.Fs,...
                            'Driver',app.data.Setup.playbackDriver,...
                            'ChannelMappingSource','Property',...
                            'ChannelMapping',app.data.Setup.playbackChannelsIn,...
                            'SamplesPerFrame',app.data.Setup.playbackBuffer);
                    end

                case 'Playrec'

                    % Create the handle
                    HeaAudioPlayRec(...
                        'fs',app.data.Setup.Fs,...
                        'channelsPlay',app.data.Setup.playbackChannelsOut,...
                        'channelsRec',app.data.Setup.playbackChannelsIn,...
                        'devicePlayID',app.data.Setup.playbackDriver);

                    % TODO Implement recording capability

            end


        end
        function getWorkspaceSetup(app)

            % SESSION SETTINGS

            % Get workspace setup (that can be saved)

            if app.AudioSettingsTabs.SelectedTab == app.QuickstartTab

                % If quick start, read from drop down menu
                app.data.Setup.TestPresentation = 'QuickStart';

                if app.TalkerDropDown.Value == "Random talker"
                    app.data.Setup.talker = "Random talker";
                    % TODO Pick a random talker
                    % app.tmp.talker = x;
                elseif app.TalkerDropDown.Value == "Mixed talkers"
                    app.data.Setup.talker = "Mixed talkers";
                    % TODO create randomization vector for mixing talkers
                    % app.tmp.talker = [];
                else
                    % Take from drop down menu
                    app.data.Setup.talker = app.TalkerDropDown.Value;
                    app.tmp.talker = app.data.Setup.talker;
                end

                if contains(app.MaskertypeDropDown.Value, "SSN")
                    % TODO if SSN selected, choose correct file per talker
                    % whichMasker = strcat(app.data.Setup.maskerType, '_', app.Setup.talker);
                    % for now:
                    whichMasker = "SSN_S";
                    app.data.Setup.maskerType = whichMasker;
                else
                    app.data.Setup.maskerType = app.MaskertypeDropDown.Value;
                end
                app.tmp.maskerType = app.data.Setup.maskerType;

                if app.ListnumberDropDown.Value == "Random list"
                    app.data.Setup.listNum = "Random list";
                    % TODO pick a random list for specific talker
                    % app.tmp.listNum = [];
                elseif app.ListnumberDropDown.Value == "Mixed list"
                    app.data.Setup.listNum = "Mixed list";
                    % TODO mix sentences of different lists
                    % app.tmp.listNum = [];
                else
                    app.data.Setup.listNum = double(string(erase(app.ListnumberDropDown.Value, "List")));
                    app.tmp.listNum = app.data.Setup.listNum;
                end

                if app.RandomizewithinlistCheckBox.Value == 1
                    app.data.Setup.RandomizedSentences = true;
                    % TODO randomize sentences in list
                else
                    app.data.Setup.RandomizedSentences = false;
                end

                app.data.Setup.targetLevel = app.TargetlevelEditField.Value;
                app.data.Setup.maskerLevel = app.MaskerlevelEditField.Value;
                app.data.Setup.SNRvalue = double(string(app.SNRvalue.Text));
                app.tmp.targetLevel = app.data.Setup.targetLevel;
                app.tmp.maskerLevel = app.data.Setup.maskerLevel;
                app.tmp.SNRvalue = app.data.Setup.SNRvalue;

                % read file with DAST sentence
                sentences = readtable(fullfile(pwd, 'files', 'DAST-sentences.xlsx'));

                % sentences for current list
                app.tmp.sentences = sentences(sentences.List == app.tmp.listNum,:);
                app.tmp.listTot = 1;

                % File locations
                app.tmp.targetDir = fullfile(app.data.Setup.Audiopath,'Speech',app.tmp.talker); % target dir
                app.tmp.maskerDir = fullfile(app.data.Setup.Audiopath,'Noise'); % masker dir
                if app.AudioVisualPresentationButton.Value == 1
                    app.tmp.videoDir = fullfile(app.data.Setup.Videopath,app.tmp.talker); % video dir
                end

            else % For advanced option

                app.data.Setup.TestPresentation = 'Advanced';

            end

            % ------------------------------------------------------------
            % Saving settings

            app.tmp.resultDir = fullfile(pwd, 'results'); % results dir
            if ~exist(app.tmp.resultDir, 'dir')
                mkdir(app.tmp.resultDir);
            end

            app.tmp.resultName = app.FilenameEditField.Value; % results file name
            app.tmp.resultFullfile = fullfile(app.tmp.resultDir, [app.tmp.resultName, '.mat']);

            % if csv file already exists (duplicating file name)
            newName = app.tmp.resultName;
            for i = 1:30
                if isfile(app.tmp.resultFullfile)
                    newName = strcat(app.tmp.resultName, '.', num2str(i));
                    app.tmp.resultFullfile = fullfile(app.tmp.resultDir, [newName, '.mat']);
                else
                    break
                end
            end
            if strcmp(app.tmp.resultName,newName) == 0
                app.tmp.resultName = newName;
            end

        end

        function setWorkspaceSetup(app)

            % Set workspace setup (that can be loaded)
            app.currentAudioDir.Value = app.data.Setup.Audiopath;
            app.currentVideoDir.Value = app.data.Setup.Videopath;

            if app.data.Setup.TestPresentation == "QuickStart"
                % If quick start, set drop down menu opts
                app.AudioSettingsTabs.SelectedTab = app.QuickstartTab;

                if app.data.Setup.talker == "Random talker"
                    app.TalkerDropDown.Value = "Random talker";
                elseif app.data.Setup.talker == "Mixed talkers"
                    app.TalkerDropDown.Value = "Mixed talkers";
                else
                    % Take from drop down menu
                    app.TalkerDropDown.Value = app.data.Setup.talker;
                end

                if contains(app.data.Setup.maskerType,"SSN")
                    % % TODO if SSN selected, choose correct file per talker
                    % for now:
                    whichMasker = "SSN";
                    app.MaskertypeDropDown.Value = whichMasker;
                else
                    app.MaskertypeDropDown.Value = app.data.Setup.maskerType;
                end

                if isa(app.data.Setup.listNum, 'numeric')
                    app.ListnumberDropDown.Value = strcat("List ", num2str(app.data.Setup.listNum));
                elseif app.data.Setup.listNum == "Random list"
                    app.ListnumberDropDown.Value = "Random list";
                elseif app.data.Setup.listNum == "Mixed list"
                    app.ListnumberDropDown.Value = "Mixed list";
                end

                if app.data.Setup.RandomizedSentences == true; app.RandomizewithinlistCheckBox.Value = 1;
                else; app.RandomizewithinlistCheckBox.Value = 0; end

                app.TargetlevelEditField.Value = app.data.Setup.targetLevel;
                app.MaskerlevelEditField.Value = app.data.Setup.maskerLevel;
                app.SNRvalue.Text = sprintf('%1.1f',app.data.Setup.SNRvalue);

            else
                % For advanced option
                app.AudioSettingsTabs.SelectedTab = app.AdvancedTab;
                app.CSVfileEditField.Value = '';
                app.UploadedCSVCheckBox.Value = 0;
            end

            % Settings
            app.LeftMeasuredEditField.Value = app.data.Setup.calibrationMeasuredLevel(1);
            app.RightMeasuredEditField.Value = app.data.Setup.calibrationMeasuredLevel(2);
            app.FilterEditField.Value = app.data.Setup.equalizationFilterPath;

            if app.data.Setup.MaskerSettings == "Continuous"; app.ContinuousButton.Value = 1;
            else; app.IntermittentButton.Value = 1; end
            app.DurationbeforeEditField.Value = app.data.Setup.DurationBefore;

            if app.data.Setup.ResultType == "Percent correct"; app.PercentageCorrectButton.Value = 1;
            else; app.SpeechReceptionThresholdButton.Value = 1; end

            if app.data.Setup.scoringType == "Keyword-based"; app.KeywordBasedButton.Value = 1;
            else; app.WordByWordButton.Value = 1; end

            if app.data.Setup.presentationMode == "Audio"; app.AudioPresentationButton.Value = 1;
            else; app.AudioVisualPresentationButton.Value = 1; end

            % Playback setup
            if app.data.Setup.playbackMode == "Mono"; app.MonoButton.Value = 1;
            elseif app.data.Setup.playbackMode == "Stereo"; app.StereoButton.Value = 1;
            else; app.MultiButton.Value = 1;
            end
            if app.data.Setup.playbackMethod == "Basic"; app.BasicButton.Value = 1;
            elseif app.data.Setup.playbackMethod == "AudioToolbox"
                app.AudioToolboxButton.Value = 1;
                app.DriverDropDown.Value = app.data.Setup.playbackDriver;
                app.ChannelsOutEditField.Value = num2str(app.data.Setup.playbackChannelsOut);
                app.ChannelsInEditField.Value = num2str(app.data.Setup.playbackChannelsIn);
            else; app.PlayrecButton.Value = 1;
            end

            % Update selected buttons
            MaskersettingsButtonGroupSelectionChanged(app)
            ScoringtypeButtonGroupSelectionChanged(app)
            playbackMethodChanged(app)
            MaskersettingsButtonGroupSelectionChanged(app)
            PlaybackmodeButtonGroupSelectionChanged(app)
            PresentationmodeButtonGroupSelectionChanged(app)

        end

        function saveBasicInformationInFile(app)

            % Write basic information and setup
            % TODO eventually save .mat file in csv or xlsx from

            % % Initialize Test variable
            % table with results
            varTypes = ["double", "double", "cell", "double", "double", "double", "string"];
            varNames = ["List", "Target", "Masker", "Target level", "Masker level", "Score", "listResult"];
            sz = [1, length(varNames)];
            app.data.Test.Results = table('Size',sz,'VariableTypes',varTypes,'VariableNames',varNames);

            if app.data.Setup.TestPresentation == "Advanced"
                app.data.Setup.listNum = 'Custom';
                app.data.Setup.talker = 'Custom';
                app.data.Setup.maskerType = 'Custom';
                app.data.Setup.targetLevel = 'Custom';
                app.data.Setup.maskerLevel = 'Custom';
            end

        end

        function savePartialResultsInFile(app)

            % % Save current results on Test variable
            if app.data.Setup.scoringType == "Word-by-word"
                % get words correctly repeated
                wordsDisplay = strsplit(string(app.tmp.sentText));
                numWordsSent = numel(wordsDisplay);
                wordsRating = zeros(1,numWordsSent);
                for i = 1:app.tmp.totButtons
                    if app.(sprintf('W%iButton', i)).Value == 1
                        wordsRating(i) = 1;
                    end
                end
                % save words and results in file
                for w = 1:numWordsSent
                    app.data.Test.Words(app.tmp.whichLine, w) = wordsDisplay(w);
                    app.data.Test.WordsScore(app.tmp.whichLine, w) = string(wordsRating(w));
                end
            end

            % For both scoring methods
            numKeywordsSent = numel(app.tmp.Keywords);
            keywordsRating = zeros(1,numKeywordsSent);
            for j = 1:length(app.tmp.KeywordsButtons)
                if app.(sprintf('W%iButton', app.tmp.KeywordsButtons(j))).Value == 1
                    keywordsRating(j) = 1;
                end
            end
            % save keywords and results in file
            for w = 1:numKeywordsSent
                app.data.Test.Keywords(app.tmp.whichLine, w) = app.tmp.Keywords(w);
                app.data.Test.KeywordsScore(app.tmp.whichLine, w) = string(keywordsRating(w));
            end

            % save scores of current sentence
            if app.data.Setup.scoringType == "Word-by-word"
                numCorrectWords = sum(wordsRating);
                currentScore = (numCorrectWords/numWordsSent)*100;
            else
                numCorrectWords = sum(keywordsRating);
                currentScore = (numCorrectWords/3)*100;
            end

            if app.data.Setup.ResultType == "Percent correct"
                app.tmp.scores = [app.tmp.scores currentScore];
                app.tmp.totalScore = round(mean(app.tmp.scores));
                %             else % TODO, save final SRT
            end

            % write results in current line
            if app.tmp.whichLine > 1 % copying first line to avoid warning
            app.data.Test.Results = vertcat(app.data.Test.Results,...
                app.data.Test.Results(1,:));
            end
            app.data.Test.Results.List(app.tmp.whichLine) = app.tmp.listNum;
            app.data.Test.Results.Target(app.tmp.whichLine) = app.tmp.sent;
            app.data.Test.Results.Masker(app.tmp.whichLine) = cellstr(app.tmp.maskerType);
            app.data.Test.Results.("Target level")(app.tmp.whichLine) = app.tmp.targetLevel;
            app.data.Test.Results.("Masker level")(app.tmp.whichLine) = app.tmp.maskerLevel;
            app.data.Test.Results.Score(app.tmp.whichLine) = round(currentScore); % with 1 decimal?

            % save mat file
            result = app.data;
            save(app.tmp.resultFullfile, 'result', '-mat')

        end

        function saveFinalResultsInFile(app)

            if app.BasicinformationPanel.Enable == "on"
                % if user pressed Stop and going back to test setup
                app.tmp.whichLine = app.tmp.whichLine - 1;
            end
            if app.tmp.whichLine <= 0
                % do nothing (start of test)
            elseif app.tmp.iteration == app.tmp.sentTot || app.tmp.list_iteration == app.tmp.listTot
                % end of the list/test
                if app.data.Setup.ResultType == "Percent correct"
                    app.data.Test.Results.listResult(app.tmp.whichLine) = [num2str(round(app.tmp.totalScore,1)), '%'];
                    % else % TODO, save final SRT
                end

                % save final mat file
                result = app.data;
                save(app.tmp.resultFullfile, 'result', '-mat')
            end

        end

        function setupPsychToolbox(app)

            sca;
            close all;
            PsychStartup;
            InitializePsychSound;
            Screen('Preference', 'SkipSyncTests', 2); 
            PsychDefaultSetup(2);
            AssertOpenGL;

            % Get the screen numbers.
            app.tmp.Video.screens = Screen('Screens');
            %Screen('Preference','ConserveVRAM',64); % May be needed for weaker systems

            % Select screen (if external exists chooses external screen)
            screenNumber = max(app.tmp.Video.screens);

            % Open an on-screen window using PsychImaging and color it grey
            [app.tmp.Video.win, windowRect] = PsychImaging('OpenWindow', screenNumber, [189 190 186]/255);
            [screenXpixels, screenYpixels] = Screen('WindowSize', app.tmp.Video.win);
            [xCenter, yCenter] = RectCenter(windowRect);
            Screen('BlendFunction', app.tmp.Video.win, 'GL_SRC_ALPHA', 'GL_ONE_MINUS_SRC_ALPHA');

            % Read Grey Screen Image and display on screen
            imgloc = fullfile(pwd,'files','GreyBackground_Screening_filled.jpg');
            [app.tmp.greyImage, ~, alpha] = imread(imgloc);
            [s1, s2, s3] = size(app.tmp.greyImage);
            app.tmp.Video.imageTexture = Screen('MakeTexture', app.tmp.Video.win, app.tmp.greyImage);
            Screen('Flip',app.tmp.Video.win,[],1);

        end

        function playSignal(app,signalToPlay)
            
            % Play using basic sound function and return
            if strcmp(app.data.Setup.playbackMethod,'Basic')

                sound(signalToPlay,app.data.Setup.Fs);
                soundLength = length(signalToPlay)/app.data.Setup.Fs;
                pause(soundLength);
                return;

            end            

            % Initialize visual part of stimuli, if relevant
            if app.AudioVisualPresentationButton.Value == 1
                initializeVisualPartOfStimuli(app);
            end

            % Duplicate signalToPlay, if relevant
            if size(signalToPlay,2)~=length(app.data.Setup.playbackChannelsOut)
                switch app.data.Setup.playbackMode
                    case 'Mono'
                        signalToPlay = repmat(signalToPlay, 1, ...
                            length(app.data.Setup.playbackChannelsOut));
                    otherwise
                        if mod(length(app.data.Setup.playbackChannelsOut),size(signalToPlay,2))==0 % duplicate
                            signalToPlay = repmat(signalToPlay, 1, ...
                                length(app.data.Setup.playbackChannelsOut)/size(signalToPlay,2));
                        end
                end
            end

            % Start acoustic stimuli if using non-blocking playbackMethod
            if strcmp(app.data.Setup.playbackMethod,'Playrec')

                % Send signal via playrec
                playrec('play',signalToPlay,app.data.Setup.playbackChannelsOut);

                % Delay playback to compensate for padding, if relevant
                if app.data.Setup.DurationBefore > 0.5 
                    pause(app.data.Setup.DurationBefore-0.5);
                end

            end

            % Loop through the stimuli for block-based playback
            app.tmp.playingFlag = true;
            app.tmp.playingIdx = 1;
            while app.tmp.playingFlag==true && app.tmp.playingIdx<size(signalToPlay,1)

                % Send the acoustic part of the buffer, if relevant
                if strcmp(app.data.Setup.playbackMethod,'AudioToolbox')
                    playAcousticPartOfBuffer(app,signalToPlay)
                end

                % Send the visual part of the buffer, if relevant
                if app.AudioVisualPresentationButton.Value == 1
                    playVisualPartOfBuffer(app);
                end

                % Drawnow and update buffer index
                drawnow;
                app.tmp.playingIdx = app.tmp.playingIdx+app.data.Setup.playbackBuffer;

            end

            % Conclude visual part of stimuli, if relevant
            if app.AudioVisualPresentationButton.Value == 1
                concludeVisualPartOfStimuli(app);
            end

            % Release audio objects            
            releaseAudioObjs(app);

        end

        function initializeVisualPartOfStimuli(app)

            % Prepare the video part of the stimuli
            app.tmp.Video.movie = Screen('OpenMovie', app.tmp.Video.win, app.tmp.videoFullfile);
            WaitSecs(2);                                                   % TODO Can this be shorter (e.g., 0.1 s)?
            app.tmp.Video.ifi = Screen('GetFlipInterval', app.tmp.Video.win);
            topPriorityLevel = MaxPriority(app.tmp.Video.win); % retreive the maximum priority number
            Priority(topPriorityLevel); % set max priority
            WaitSecs(0.5);                                                 % TODO Can this be shorter (e.g., 0.1 s)?
            app.tmp.Video.vbl = Screen('Flip', app.tmp.Video.win);
            tic;
            [app.tmp.Video.droppedframes] = Screen('PlayMovie', app.tmp.Video.movie, 1);

        end

        function playAcousticPartOfBuffer(app,signalToPlay)

            if (app.tmp.playingIdx+app.data.Setup.playbackBuffer-1)<size(signalToPlay,1)
                signalToPlayBuffered = signalToPlay(app.tmp.playingIdx:(app.tmp.playingIdx+app.data.Setup.playbackBuffer-1),:);
            else
                zeroPadLen = app.data.Setup.playbackBuffer-size(signalToPlay(app.tmp.playingIdx:end,:),1);
                signalToPlayBuffered = [signalToPlay(app.tmp.playingIdx:end,:);
                    zeros(zeroPadLen,size(signalToPlay,2))];
            end
            app.data.Setup.playbackObjectPlayer(signalToPlayBuffered);

        end

        function playVisualPartOfBuffer(app)

            % Wait for next movie frame, retrieve texture handle to it
            tex = Screen('GetMovieImage', app.tmp.Video.win, app.tmp.Video.movie);

            % Break if movie is done (i.e., if texture is invalid)
            if tex <= 0
                return;
            end

            % Draw the new texture immediately to screen
            Screen('DrawTexture', app.tmp.Video.win, tex);

            % Update the display
            Screen('Flip', app.tmp.Video.win, 0);

            % Release the texture
            Screen('Close', tex);

        end

        function concludeVisualPartOfStimuli(app)

            % Stop playback
            Screen('PlayMovie', app.tmp.Video.movie, 0);

            % Close movie
            Screen('CloseMovie', app.tmp.Video.movie);
            Screen('DrawTexture', app.tmp.Video.win, app.tmp.Video.imageTexture);
            Screen('Flip', app.tmp.Video.win,[], 1);
            if app.tmp.iteration == app.tmp.sentTot
                Screen('MakeTexture', app.tmp.Video.win, app.tmp.greyImage);
                Screen('Flip',app.tmp.Video.win,[],1);
            end
            FlushEvents();  

        end

    end


    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)

            % intialize variables
            app.data = [];
            app.tmp = [];

            % Shuffle randomization
            rng('shuffle');

            % Intializing variables
            [currentfilepath,~,~] = fileparts(mfilename('fullpath'));
            if contains(currentfilepath, "DAST.app") % for executable version
                currentfilepath = extractBefore(currentfilepath, "DAST.app");
            end
            cd (currentfilepath)

            formatOut = 'yyyy_mm_dd';
            app.data.Setup.Date = datestr(now,formatOut);
            app.FilenameEditField.Value = sprintf(app.data.Setup.Date);

            % Audio and video directories
            app.currentAudioDir.Value = fullfile(pwd, 'audio');
            app.currentVideoDir.Value = fullfile(pwd, 'video');

            % File location for setup files
            app.tmp.setupDir = fullfile(pwd, 'files', 'setup files');
            if ~exist(app.tmp.setupDir, 'dir')
                mkdir(app.tmp.setupDir);
            end

            % Keywords and Buttons
            app.tmp.KeywordsButtons = [];
            app.tmp.totButtons = 12;

            % Calibration
            app.data.Setup.dBSPLRef = 95; % 95 dB SPL is equivalent to -26 dB FS
            app.data.Setup.dBFSRef = -26;

            % Ramping
            app.data.Setup.hannLen = 50e-3*48e3; % 50ms of ramping up and down of the masker (i.e., based on 48 kHz sampling freq)
            app.data.Setup.hannWindow = hann(2*50e-3*48e3);
            app.data.Setup.Fs = 48e3; % Fs = 48 kHz

            % Disable buttons
            disableButtons_initialState(app)
            app.tmp.whichLine = -1;
            app.StartinglistDropDown.Visible = "off";
            app.StartinglistDropDownLabel.Visible = "off";

            % Playback setup
            app.data.Setup.equalizationFilter = [];
            app.data.Setup.playbackChannelsIn = [];
            app.data.Setup.playbackChannelsOut = [];

            app.data.Setup.playbackDriver = '';
            app.data.Setup.playbackObjectPlayer = [];
            app.data.Setup.playbackObjectRecorder = [];
            app.tmp.playbackObjectFileWriter = [];
            app.data.Setup.playbackBuffer = 2^14; % The input frame size must be less than or equal to twice the SampleRate property value (or two seconds of audio samples).    --> NOTE: There is some confusion about the relation to this and the ASIO settings (i.e., open them via the command window with the code "asiosettings(app.data.Setup.playbackObjectPlayer)") (Jens Bo uses 2*4*1024???)
            app.tmp.recordingFlag = false;
            app.tmp.playingFlag = false;

            % Sets default variables from "Settings" tab
            ApplySettingsButtonPushed(app)
            app.ApplySettingsButton.Text = 'Apply settings';
            app.SavePresetButton.Text = 'Save preset';

        end

        % Value changed function: FilenameEditField, 
        % ...and 2 other components
        function TestInfoChanged(app, event)
            if isempty(app.TesterIDEditField.Value); Leader = '';
            else; Leader = strcat('_', app.TesterIDEditField.Value); end
            if isempty(app.ParticipantIDEditField.Value); Subject = '';
            else; Subject = strcat(app.ParticipantIDEditField.Value, '_'); end

            app.FilenameEditField.Value = sprintf(strcat(Subject, app.data.Setup.Date, Leader));
        end

        % Button pushed function: StartButton
        function StartButtonPushed(app, event)

%             try

                % Enable buttons
                enableButtons_testState(app)
                app.ContinueButton.Enable = "off";
                app.SentencesLabel.Text = 'Sentence';

                % Get Workspace settings
                getWorkspaceSetup(app)
                setWorkspaceSetup(app)
    
                % Save basic information and setup in file
                saveBasicInformationInFile(app)
 
                % Update files names
                app.tmp.scores = [];
                app.tmp.totalScore = 0;
    
                app.tmp.iteration = 0; % current sentence
                app.tmp.whichLine = 0; % initializing line for saving results
    
                if app.AudioSettingsTabs.SelectedTab == app.AdvancedTab
                    chosenOption = find(app.tmp.lists == double(string(app.StartinglistDropDown.Value)));
                    app.tmp.list_iteration = chosenOption; % current list from dropdown
                else
                    app.tmp.list_iteration = 1; % current list
                end
    
                %--------------------------------------------------------
                % Update file locations/names
    
                updateInformation(app);
                if app.AudioVisualPresentationButton.Value == 1
                    setupPsychToolbox(app);
                    pause(0.1);
                end
    
                % Load, mix, and play the sentence
                pause(0.1);
                loadMixAndPlaySentence(app);
                %--------------------------------------------------------

%             catch
%                 if app.AudioSettingsTabs.SelectedTab == app.QuickstartTab
%                     errordlg('Please check input parameters for quick start');
%                     disableButtons_initialState(app)
%                 elseif app.AudioSettingsTabs.SelectedTab == app.AdvancedTab
%                     errordlg('Please check input parameters for advanced options.');
%                     disableButtons_initialState(app)
%                 else
%                     errordlg('Please check input parameters.');
%                     disableButtons_initialState(app)
%                 end
%             end

        end

        % Value changed function: MarkallButton
        function MarkallButtonValueChanged(app, event)
            value = app.MarkallButton.Value;
            for i = 1:app.tmp.totButtons
                if app.(sprintf('W%iButton', i)).Visible == "on"
                    if (value == 1 && app.data.Setup.scoringType ~= "Keyword-based") ||...
                            (value == 1 && app.data.Setup.scoringType == "Keyword-based" && any(app.tmp.KeywordsButtons == i))
                        app.(sprintf('W%iButton', i)).Value = 1;
                    else
                        app.(sprintf('W%iButton', i)).Value = 0;
                    end
                end
            end
        end

        % Button pushed function: ContinueButton
        function ContinueButtonPushed(app, event)

            app.ContinueButton.Enable = "off";

            % release audio objects
            releaseAudioObjs(app)

            % Save current results
            savePartialResultsInFile(app)

            for i = 1:app.tmp.totButtons
                app.(sprintf('W%iButton', i)).Value = 0;
                app.(sprintf('W%iButton', i)).FontWeight = 'normal';
                app.(sprintf('W%iButton', i)).Enable = 'on';
            end
            app.tmp.KeywordsButtons = [];
            app.MarkallButton.Value = 0;

            if app.tmp.iteration < app.tmp.sentTot 

                % next sentence
                %--------------------------------------------------------
                % Update file locations/names
                updateInformation(app);

                % Load, mix, and play the sentence
                pause(0.1);
                loadMixAndPlaySentence(app);
                %--------------------------------------------------------

            elseif app.tmp.list_iteration < app.tmp.listTot

                % Save results for list
                saveFinalResultsInFile(app)

                % Going to next list by pressing button
                app.ContinueButton.Enable = "off";
                pause(0.01)
                app.NextlistButton.Visible = "on";
                app.NextlistButton.Enable = "on";

                app.tmp.scores = [];
                app.tmp.totalScore = 0;

                app.SentencesLabel.Text = ['List ', num2str(app.tmp.listNum), ' done.'];
                app.SentencesLabel.FontSize = 20;

                % make all buttons invisible
                for button = 1:app.tmp.totButtons
                    app.(sprintf('W%iButton', button)).Visible = "off";
                end
                app.PauseButton.Enable = "off";
                app.MarkallButton.Visible = "off";
                app.Instructions.Visible = "off";

            else

                % Save results for list/end of test
                saveFinalResultsInFile(app)

                % end of test
                disableButtons_initialState(app);
                pause(0.01)
                app.SentencesLabel.Visible = "on";
                app.SentencesLabel.Text = ['List ', num2str(app.tmp.listNum), ' done. Test completed.'];
                app.SentencesLabel.FontSize = 20;

            end

        end

        % Button pushed function: StopButton
        function StopButtonPushed(app, event)

            % release audio and video objects
            releaseAudioObjs(app)

            if app.AudioVisualPresentationButton == 1
                sca;
            end

            % disable buttons
            disableButtons_initialState(app)

            app.SentencesLabel.Visible = "on";
            app.SentencesLabel.Text = 'Test terminated';
            app.SentencesLabel.FontSize = 20;

            % Save results for list/end of test
            saveFinalResultsInFile(app)

        end

        % Value changed function: PauseButton
        function PauseButtonValueChanged(app, event)

            % Pressing pause
            if app.PauseButton.Value == 1

                % release audio objects
                releaseAudioObjs(app)

                app.SentencesLabel.Text = 'Test paused';
                app.SentencesLabel.FontSize = 20;

                % make buttons invisible
                % saving info on which are currently visible
                app.tmp.activeButtons = zeros(1, app.tmp.totButtons);
                for button = 1:app.tmp.totButtons
                    if app.(sprintf('W%iButton', button)).Visible == "on"
                        app.tmp.activeButtons(button) = 1;
                    end
                    app.(sprintf('W%iButton', button)).Visible = "off";
                end

                app.ContinueButton.Enable = "off";
                app.MarkallButton.Visible = "off";
                app.Instructions.Visible = "off";

            else

                % Pressing again for continuing test enabling buttons
                app.SentencesLabel.Text = 'Sentence';
                app.SentencesLabel.FontSize = 14;

                % make all buttons visible
                for button = 1:app.tmp.totButtons
                    if app.tmp.activeButtons(button) == 1
                        app.(sprintf('W%iButton', button)).Visible = "on";
                    end
                end
                app.MarkallButton.Visible = "on";
                app.Instructions.Visible = "on";

                % Load, mix, and play the sentence again
                pause(0.1);
                loadMixAndPlaySentence(app);
                %--------------------------------------------------------
            end

        end

        % Value changed function: W1Button, W2Button, W3Button, W4Button, 
        % ...and 4 other components
        function wordSelected(app, event)
            values = zeros(1,app.tmp.totButtons);
            for i = 1:app.tmp.totButtons
                values(i) =  app.(sprintf('W%iButton', i)).Value;
            end
            if any(values == 0)
                app.MarkallButton.Value = 0;
            end

        end

        % Button pushed function: UploadButton
        function UploadButtonPushed(app, event)
            [filename,pathname] = uigetfile(fullfile(pwd,'files','*.csv'));
            if filename==0
                % user pressed cancel
                drawnow;
                figure(app.UIFigure)
                return
            else
                try
                    app.CSVfileEditField.Value = fullfile(pathname,filename);
                    app.UploadedCSVCheckBox.Value = 1;
                    app.StartinglistDropDownLabel.Visible = "on";
                    app.StartinglistDropDown.Visible = "on";

                    % Read from csv file
                    opts = detectImportOptions(app.CSVfileEditField.Value);
                    opts.Encoding = 'UTF-8';
                    opts.VariableNamingRule = 'Preserve';
                    app.tmp.sentencesFull = readtable(app.CSVfileEditField.Value, opts);  % uploaded csv with test info
                    app.tmp.lists = unique(app.tmp.sentencesFull.List, 'stable'); % get lists
                    app.tmp.listTot = length(app.tmp.lists);

                    % Showing list options in menu
                    app.StartinglistDropDown.Items = cellstr(num2str(app.tmp.lists));

                    drawnow;
                    figure(app.UIFigure)

                catch
                    figure(app.UIFigure)
                    app.CSVfileEditField.Value = '';
                    app.UploadedCSVCheckBox.Value = 0;
                    app.StartinglistDropDownLabel.Visible = "off";
                    app.StartinglistDropDown.Visible = "off";
                    errordlg('Invalid CSV file. Please upload a file with the right format.');
                    drawnow;
                    disableButtons_initialState(app)
                end

                app.data.Setup.UploadedCSV = app.CSVfileEditField.Value;

            end
        end

        % Button pushed function: SelectAudioDirButton
        function SelectAudioDirButtonPushed(app, event)
            audiopath = uigetdir;

            if audiopath==0
                % user pressed cancel
                drawnow;
                figure(app.UIFigure)
                return
            else
                app.currentAudioDir.Value = audiopath;
                drawnow;
                figure(app.UIFigure)
            end

        end

        % Value changed function: PlaycalibrationfileButton
        function PlaycalibrationfileButtonPushed(app, event)

            % get settings from playback method and mode
            getPlaybackSettings(app);

            value = app.PlaycalibrationfileButton.Value;

            if value == 0
                % if already pressed, stop sound
                releaseAudioObjs(app)

            else
                % if pressed, play tone

                % Read calibration signal 
                % TODO Change this to select speaker-specific SSN
                calibrationToneFullFile = fullfile(app.currentAudioDir.Value,'Noise','SSN_S.wav');
                calibrationInfo = audioinfo(calibrationToneFullFile);

                % Read in random segment (and only 1 minute)
                maxCalTime = 60; % seconds
                lengthCalSignal = maxCalTime*app.data.Setup.Fs;
                randSample = round((calibrationInfo.TotalSamples-lengthCalSignal)*rand);

                calibrationSignal = audioread(calibrationToneFullFile,...
                    [randSample, randSample+lengthCalSignal-1]);

                % Apply equalization filter, if relevant
                if ~isempty(app.data.Setup.equalizationFilter)
                    calibrationSignal = repmat(calibrationSignal,1,size(app.data.Setup.equalizationFilter,2));
                    
%                     % HERE
%                     figure; freqz(calibrationSignal(:,1)); title ("Left, before")
%                     figure; freqz(calibrationSignal(:,2)); title ("Right, before")

                    for cc = 1:size(app.data.Setup.equalizationFilter,2)
                        calibrationSignal(:,cc) = filter(app.data.Setup.equalizationFilter(:,cc),1,calibrationSignal(:,cc));
                    end

                    % HERE
%                     figure; freqz(calibrationSignal(:,1)); title ("Left, after")
%                     figure; freqz(calibrationSignal(:,2)); title ("Right, after")
% 
                    figure; freqz(app.data.Setup.equalizationFilter(:,1)); title("Filter left")
                    figure; freqz(app.data.Setup.equalizationFilter(:,2)); title("Filter right")

                end

                % Play the calibration file
                playSignal(app,calibrationSignal);

            end
            % TODO Add continuous looped playback

        end

        % Button pushed function: NextlistButton
        function NextlistButtonPushed(app, event)

            % if button pressed
            app.NextlistButton.Visible = "off";
            app.NextlistButton.Enable = "off";

            app.SentencesLabel.Text = 'Sentence';
            app.SentencesLabel.FontSize = 14;

            % make all buttons visible
            for button = 1:app.tmp.totButtons
                app.(sprintf('W%iButton', button)).Visible = "on";
            end
            app.ContinueButton.Enable = "off";
            app.PauseButton.Enable = "on";
            app.MarkallButton.Visible = "on";
            app.Instructions.Visible = "on";

            app.tmp.list_iteration = app.tmp.list_iteration + 1;
            app.tmp.iteration = 0;

            %--------------------------------------------------------
            % Update file locations/names
            updateInformation(app);

            % Load, mix, and play the sentence
            pause(0.1);
            loadMixAndPlaySentence(app);
            %--------------------------------------------------------

        end

        % Selection changed function: PlaybackmethodButtonGroup
        function playbackMethodChanged(app, event)

            app.ApplySettingsButton.Text = 'Apply settings';
            app.SavePresetButton.Text = 'Save preset';

            % Handle radio buttons
            if app.BasicButton.Value == 1
                app.data.Setup.playbackMethod = 'Basic';
                app.DriverDropDown.Enable = "off";
                app.DriverDropDownLabel.Enable = "off";
                app.ChannelsOutEditField.Enable = "off";
                app.ChannelsOutEditFieldLabel.Enable = "off";
                app.ChannelsInEditField.Enable = "off";
                app.ChannelsInEditFieldLabel.Enable = "off";    

                app.ChannelsOutEditField.Value = '';
                app.ChannelsInEditField.Value = '';

            elseif app.AudioToolboxButton.Value == 1
                app.data.Setup.playbackMethod = 'AudioToolbox';
                app.DriverDropDown.Enable = "on";
                app.DriverDropDownLabel.Enable = "on";
                app.ChannelsOutEditField.Enable = "on";
                app.ChannelsOutEditFieldLabel.Enable = "on";
                app.ChannelsInEditField.Enable = "on";
                app.ChannelsInEditFieldLabel.Enable = "on";

                if ispc % if windows
                    app.DriverDropDown.Items = {'ASIO', 'DirectSound', 'WASAPI'};
                elseif ismac % if mac
                    app.DriverDropDown.Items = {'CoreAudio'};
                else % if linux (isunix)
                    app.DriverDropDown.Items = {'ALSA'};
                end
            else
                app.data.Setup.playbackMethod = 'Playrec';
                app.DriverDropDown.Enable = "off";
                app.DriverDropDownLabel.Enable = "off";
                app.ChannelsOutEditField.Enable = "off";
                app.ChannelsOutEditFieldLabel.Enable = "off";
                app.ChannelsInEditField.Enable = "off";
                app.ChannelsInEditFieldLabel.Enable = "off";

                app.ChannelsOutEditField.Value = '';
                app.ChannelsInEditField.Value = '';
            end
        end

        % Close request function: UIFigure
        function UIFigureCloseRequest(app, event)
            YN = uiconfirm(app.UIFigure,'Do you want to close the window?', 'Close request');
            if strcmpi(YN,'OK')

                % release audio objects
                releaseAudioObjs(app)

                % Save results for list/end of test
                saveFinalResultsInFile(app)

                % Delete the app
                delete(app);
            end
        end

        % Button pushed function: ImportequalizationfilterButton
        function ImportequalizationfilterButtonPushed(app, event)
            [filename,pathname] = uigetfile('*.mat');

            if filename==0
                % user pressed cancel
                drawnow;
                figure(app.UIFigure);
                app.FilterEditField.Value = '';
                return
            else
                fullfilename = fullfile(pathname,filename);
                load(fullfilename); 

                % storing equalization filter content and path
                app.data.Setup.equalizationFilter = equalizationFilter;
                app.data.Setup.equalizationFilterPath = fullfilename;
                app.FilterEditField.Value = fullfilename;

                drawnow;
                figure(app.UIFigure);

            end
        end

        % Button pushed function: SavePresetButton
        function SavePresetButtonPushed(app, event)

            % Get user input for filename - workspace
            [file,path] = uiputfile(fullfile(app.tmp.setupDir,'*.mat'),'Save workspace setup');

            app.UploadedWorkspaceEditField.Value = '';

            if file==0
                % user pressed cancel
                drawnow;
                figure(app.UIFigure);
                return
            else
                % Get Workspace settings
                ApplySettingsButtonPushed(app)
                getWorkspaceSetup(app)
                setup = app.data.Setup;

                % Save current workspace file into mat file
                save(fullfile(path, file), 'setup', '-mat')
                drawnow;
                figure(app.UIFigure);
                app.SavePresetButton.Text = 'Preset saved';

            end

        end

        % Button pushed function: LoadPresetButton
        function LoadPresetButtonPushed(app, event)

            [filename,pathname] = uigetfile(fullfile(app.tmp.setupDir,'*.mat'));
            if filename==0
                % user pressed cancel
                drawnow;
                figure(app.UIFigure);
                app.UploadedWorkspaceEditField.Value = '';
                return
            else
                % loading variable
                fullfilename = fullfile(pathname,filename);
                load(fullfilename);
                app.data.Setup = setup;

                [~,f,~]=fileparts(fullfilename);
                filename=fullfile(f);

                app.UploadedWorkspaceEditField.Value = filename;
                drawnow;
                figure(app.UIFigure);

                % Updating workspace settings with current
                setWorkspaceSetup(app)

                app.ApplySettingsButton.Text = 'Settings applied';
                app.SavePresetButton.Text = 'Preset saved';

            end

        end

        % Selection changed function: MaskersettingsButtonGroup
        function MaskersettingsButtonGroupSelectionChanged(app, event)

            app.ApplySettingsButton.Text = 'Apply settings';
            app.SavePresetButton.Text = 'Save preset';

            if app.IntermittentButton.Value == 1
                app.DurationbeforeEditField.Enable = "on";
                app.DurationbeforeEditFieldLabel.Enable = "on";
                app.SecLabel.Enable = "on";
            else
                app.DurationbeforeEditField.Enable = "off";
                app.DurationbeforeEditFieldLabel.Enable = "off";
                app.SecLabel.Enable = "off";
            end
        end

        % Button pushed function: ApplySettingsButton
        function ApplySettingsButtonPushed(app, event)

            % Applies and saves in locally the current "Settings" tab
            app.ApplySettingsButton.Text = 'Settings applied';

            % ------------------------------------------------------------
            % Experiment settings
            app.data.Setup.Audiopath = app.currentAudioDir.Value;
            app.data.Setup.Videopath = app.currentVideoDir.Value; %HERE

            if app.ContinuousButton.Value == 1; app.data.Setup.MaskerSettings = 'Continuous';
            else; app.data.Setup.MaskerSettings = 'Intermittent'; end
            app.data.Setup.DurationBefore = app.DurationbeforeEditField.Value; % [sec.] time between noise onset and sentence start

            if app.PercentageCorrectButton.Value == 1; app.data.Setup.ResultType = 'Percent correct';
            else; app.data.Setup.ResultType = 'Speech reception threshold'; end

            if app.KeywordBasedButton.Value == 1; app.data.Setup.scoringType = 'Keyword-based';
            else; app.data.Setup.scoringType = 'Word-by-word'; end

            if app.AudioPresentationButton.Value == 1; app.data.Setup.presentationMode = 'Audio';
            else; app.data.Setup.presentationMode = 'Audio-visual'; end

            % ------------------------------------------------------------
            % Calibration Settings
            calibrationLeft = app.LeftMeasuredEditField.Value;
            calibrationRight = app.RightMeasuredEditField.Value;
            app.data.Setup.calibrationMeasuredLevel = [calibrationLeft calibrationRight];
            app.data.Setup.equalizationFilterPath = app.FilterEditField.Value;
            if ~isempty(app.data.Setup.equalizationFilterPath)
                load(app.data.Setup.equalizationFilterPath)
                app.data.Setup.equalizationFilter = equalizationFilter;
            else
                app.data.Setup.equalizationFilter = [];
            end

            % ------------------------------------------------------------
            % Getting and setting playback method
            getPlaybackSettings(app)

        end

        % Selection changed function: ResultTypeButtonGroup
        function ResultTypeButtonGroupSelectionChanged(app, event)
            app.ApplySettingsButton.Text = 'Apply settings';
            app.SavePresetButton.Text = 'Save preset';

        end

        % Selection changed function: ScoringtypeButtonGroup
        function ScoringtypeButtonGroupSelectionChanged(app, event)
            app.ApplySettingsButton.Text = 'Apply settings';
            app.SavePresetButton.Text = 'Save preset';

        end

        % Selection changed function: PlaybackmodeButtonGroup
        function PlaybackmodeButtonGroupSelectionChanged(app, event)
            app.ApplySettingsButton.Text = 'Apply settings';
            app.SavePresetButton.Text = 'Save preset';

        end

        % Selection changed function: PresentationmodeButtonGroup
        function PresentationmodeButtonGroupSelectionChanged(app, event)
            app.ApplySettingsButton.Text = 'Apply settings';
            app.SavePresetButton.Text = 'Save preset';

            if app.AudioPresentationButton.Value == 1
                app.currentVideoDir.Enable = "off";
                app.PathtofilesLabel_2.Enable = "off";
                app.SelectVideoDirButton.Enable = "off";
            else
                app.currentVideoDir.Enable = "on";
                app.PathtofilesLabel_2.Enable = "on";
                app.SelectVideoDirButton.Enable = "on";
            end
                
        end

        % Selection changed function: CalibrationButtonGroup
        function CalibrationButtonGroupSelectionChanged(app, event)
            app.ApplySettingsButton.Text = 'Apply settings';
            app.SavePresetButton.Text = 'Save preset';

        end

        % Selection change function: AudioSettingsTabs
        function AudioSettingsTabsSelectionChanged(app, event)
            app.SavePresetButton.Text = 'Save preset';
        end

        % Value changed function: TalkerDropDown
        function TalkerDropDownValueChanged(app, event)
            app.SavePresetButton.Text = 'Save preset';
        end

        % Value changed function: MaskertypeDropDown
        function MaskertypeDropDownValueChanged(app, event)
            app.SavePresetButton.Text = 'Save preset';

        end

        % Value changed function: ListnumberDropDown
        function ListnumberDropDownValueChanged(app, event)
            app.SavePresetButton.Text = 'Save preset';

        end

        % Value changed function: RandomizewithinlistCheckBox
        function RandomizewithinlistCheckBoxValueChanged(app, event)
            app.SavePresetButton.Text = 'Save preset';

        end

        % Value changed function: TargetlevelEditField
        function TargetlevelEditFieldValueChanged(app, event)
            app.SavePresetButton.Text = 'Save preset';
            val = double(app.TargetlevelEditField.Value - app.MaskerlevelEditField.Value);
            app.SNRvalue.Text = sprintf('%1.1f', val);
        end

        % Value changed function: MaskerlevelEditField
        function MaskerlevelEditFieldValueChanged(app, event)
            app.SavePresetButton.Text = 'Save preset';
            val = double(app.TargetlevelEditField.Value - app.MaskerlevelEditField.Value);
            app.SNRvalue.Text = sprintf('%1.1f', val);
        end

        % Value changed function: DurationbeforeEditField
        function DurationbeforeEditFieldValueChanged(app, event)
            app.ApplySettingsButton.Text = 'Apply settings';
            app.SavePresetButton.Text = 'Save preset';
        end

        % Value changed function: LeftMeasuredEditField
        function LeftMeasuredEditFieldValueChanged(app, event)
            app.ApplySettingsButton.Text = 'Apply settings';
            app.SavePresetButton.Text = 'Save preset';
        end

        % Value changed function: RightMeasuredEditField
        function RightMeasuredEditFieldValueChanged(app, event)
            app.ApplySettingsButton.Text = 'Apply settings';
            app.SavePresetButton.Text = 'Save preset';
        end

        % Value changed function: FilterEditField
        function FilterEditFieldValueChanged(app, event)
            app.ApplySettingsButton.Text = 'Apply settings';
            app.SavePresetButton.Text = 'Save preset';
        end

        % Value changed function: currentAudioDir
        function currentAudioDirValueChanged(app, event)
            app.ApplySettingsButton.Text = 'Apply settings';
            app.SavePresetButton.Text = 'Save preset';
        end

        % Value changed function: ChannelsOutEditField
        function ChannelsOutEditFieldValueChanged(app, event)
            app.ApplySettingsButton.Text = 'Apply settings';
            app.SavePresetButton.Text = 'Save preset';
        end

        % Value changed function: ChannelsInEditField
        function ChannelsInEditFieldValueChanged(app, event)
            app.ApplySettingsButton.Text = 'Apply settings';
            app.SavePresetButton.Text = 'Save preset';
        end

        % Value changed function: currentVideoDir
        function currentVideoDirValueChanged(app, event)
            app.ApplySettingsButton.Text = 'Apply settings';
            app.SavePresetButton.Text = 'Save preset';
        end

        % Button pushed function: SelectVideoDirButton
        function SelectVideoDirButtonPushed(app, event)
            videopath = uigetdir;

            if videopath==0
                % user pressed cancel
                drawnow;
                figure(app.UIFigure)
                return
            else
                app.currentVideoDir.Value = videopath;
                drawnow;
                figure(app.UIFigure)
            end
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.UIFigure.CloseRequestFcn = createCallbackFcn(app, @UIFigureCloseRequest, true);
            app.BasicinformationPanel = uipanel(app.UIFigure);
            app.BasicinformationPanel.Title = 'Basic information';
            app.TesterIDEditFieldLabel = uilabel(app.BasicinformationPanel);
            app.TesterIDEditFieldLabel.Text = 'Tester ID';
            app.TesterIDEditField = uieditfield(app.BasicinformationPanel, 'text');
            app.TesterIDEditField.ValueChangedFcn = createCallbackFcn(app, @TestInfoChanged, true);
            app.ParticipantIDEditFieldLabel = uilabel(app.BasicinformationPanel);
            app.ParticipantIDEditFieldLabel.Text = 'Participant ID';
            app.ParticipantIDEditField = uieditfield(app.BasicinformationPanel, 'text');
            app.ParticipantIDEditField.ValueChangedFcn = createCallbackFcn(app, @TestInfoChanged, true);
            app.FilenameEditFieldLabel = uilabel(app.BasicinformationPanel);
            app.FilenameEditFieldLabel.Text = 'File name';
            app.FilenameEditField = uieditfield(app.BasicinformationPanel, 'text');
            app.FilenameEditField.ValueChangedFcn = createCallbackFcn(app, @TestInfoChanged, true);
            app.FilenameEditField.Editable = 'off';
            app.TabGroup = uitabgroup(app.UIFigure);
            app.TestTab = uitab(app.TabGroup);
            app.TestTab.Title = 'Test';
            app.StartButton = uibutton(app.TestTab, 'push');
            app.StartButton.ButtonPushedFcn = createCallbackFcn(app, @StartButtonPushed, true);
            app.StartButton.Text = 'Start';
            app.StopButton = uibutton(app.TestTab, 'push');
            app.StopButton.ButtonPushedFcn = createCallbackFcn(app, @StopButtonPushed, true);
            app.StopButton.Text = 'Stop';
            app.DanskStningstestLabel = uilabel(app.TestTab);
            app.DanskStningstestLabel.Text = 'Dansk Sætningstest';
            app.ContinueButton = uibutton(app.TestTab, 'push');
            app.ContinueButton.ButtonPushedFcn = createCallbackFcn(app, @ContinueButtonPushed, true);
            app.ContinueButton.Text = 'Continue';
            app.currentList = uilabel(app.TestTab);
            app.currentList.Tag = 'currentList';
            app.currentList.Text = 'currentList';
            app.currentSent = uilabel(app.TestTab);
            app.currentSent.Tag = 'currentSent';
            app.currentSent.Text = 'currentSent';
            app.SentencesLabel = uilabel(app.TestTab);
            app.SentencesLabel.Text = 'Sentence';
            app.Instructions = uilabel(app.TestTab);
            app.Instructions.Text = 'Mark correctly repeated words';
            app.MarkallButton = uibutton(app.TestTab, 'state');
            app.MarkallButton.ValueChangedFcn = createCallbackFcn(app, @MarkallButtonValueChanged, true);
            app.MarkallButton.Text = 'Mark all';
            app.PauseButton = uibutton(app.TestTab, 'state');
            app.PauseButton.ValueChangedFcn = createCallbackFcn(app, @PauseButtonValueChanged, true);
            app.PauseButton.Text = 'Pause';
            app.W1Button = uibutton(app.TestTab, 'state');
            app.W1Button.ValueChangedFcn = createCallbackFcn(app, @wordSelected, true);
            app.W1Button.Text = 'W1';
            app.W2Button = uibutton(app.TestTab, 'state');
            app.W2Button.ValueChangedFcn = createCallbackFcn(app, @wordSelected, true);
            app.W2Button.Text = 'W2';
            app.W3Button = uibutton(app.TestTab, 'state');
            app.W3Button.ValueChangedFcn = createCallbackFcn(app, @wordSelected, true);
            app.W3Button.Text = 'W3';
            app.W4Button = uibutton(app.TestTab, 'state');
            app.W4Button.ValueChangedFcn = createCallbackFcn(app, @wordSelected, true);
            app.W4Button.Text = 'W4';
            app.W5Button = uibutton(app.TestTab, 'state');
            app.W5Button.ValueChangedFcn = createCallbackFcn(app, @wordSelected, true);
            app.W5Button.Text = 'W5';
            app.W6Button = uibutton(app.TestTab, 'state');
            app.W6Button.ValueChangedFcn = createCallbackFcn(app, @wordSelected, true);
            app.W6Button.Text = 'W6';
            app.W7Button = uibutton(app.TestTab, 'state');
            app.W7Button.ValueChangedFcn = createCallbackFcn(app, @wordSelected, true);
            app.W7Button.Text = 'W7';
            app.W8Button = uibutton(app.TestTab, 'state');
            app.W8Button.ValueChangedFcn = createCallbackFcn(app, @wordSelected, true);
            app.W8Button.Text = 'W8';
            app.W9Button = uibutton(app.TestTab, 'state');
            app.W9Button.Text = 'W9';
            app.W10Button = uibutton(app.TestTab, 'state');
            app.W10Button.Text = 'W10';
            app.W11Button = uibutton(app.TestTab, 'state');
            app.W11Button.Text = 'W11';
            app.NextlistButton = uibutton(app.TestTab, 'push');
            app.NextlistButton.ButtonPushedFcn = createCallbackFcn(app, @NextlistButtonPushed, true);
            app.NextlistButton.Text = 'Next list';
            app.W12Button = uibutton(app.TestTab, 'state');
            app.W12Button.Text = 'W12';
            app.SettingsTab = uitab(app.TabGroup);
            app.SettingsTab.Title = 'Settings';
            app.SettingsPanelinSettingsTab = uipanel(app.SettingsTab);
            app.SettingsPanelinSettingsTab.Tag = 'Panel for more test settings';
            app.ResultTypeButtonGroup = uibuttongroup(app.SettingsPanelinSettingsTab);
            app.ResultTypeButtonGroup.SelectionChangedFcn = createCallbackFcn(app, @ResultTypeButtonGroupSelectionChanged, true);
            app.ResultTypeButtonGroup.Title = 'Result type';
            app.PercentageCorrectButton = uiradiobutton(app.ResultTypeButtonGroup);
            app.PercentageCorrectButton.Text = 'Percentage correct';
            app.PercentageCorrectButton.Value = true;
            app.SpeechReceptionThresholdButton = uiradiobutton(app.ResultTypeButtonGroup);
            app.SpeechReceptionThresholdButton.Enable = 'off';
            app.SpeechReceptionThresholdButton.Text = 'Speech reception threshold';
            app.PresentationmodeButtonGroup = uibuttongroup(app.SettingsPanelinSettingsTab);
            app.PresentationmodeButtonGroup.SelectionChangedFcn = createCallbackFcn(app, @PresentationmodeButtonGroupSelectionChanged, true);
            app.PresentationmodeButtonGroup.Title = 'Presentation mode';
            app.AudioPresentationButton = uiradiobutton(app.PresentationmodeButtonGroup);
            app.AudioPresentationButton.Text = 'Audio';
            app.AudioPresentationButton.Value = true;
            app.AudioVisualPresentationButton = uiradiobutton(app.PresentationmodeButtonGroup);
            app.AudioVisualPresentationButton.Text = 'Audio-visual';
            app.PathtofilesLabel = uilabel(app.PresentationmodeButtonGroup);
            app.PathtofilesLabel.Text = 'Audio path';
            app.currentAudioDir = uieditfield(app.PresentationmodeButtonGroup, 'text');
            app.currentAudioDir.ValueChangedFcn = createCallbackFcn(app, @currentAudioDirValueChanged, true);
            app.currentAudioDir.Editable = 'off';
            app.SelectAudioDirButton = uibutton(app.PresentationmodeButtonGroup, 'push');
            app.SelectAudioDirButton.ButtonPushedFcn = createCallbackFcn(app, @SelectAudioDirButtonPushed, true);
            app.SelectAudioDirButton.Text = 'Select';
            app.PathtofilesLabel_2 = uilabel(app.PresentationmodeButtonGroup);
            app.PathtofilesLabel_2.Enable = 'off';
            app.PathtofilesLabel_2.Text = 'Video path';
            app.currentVideoDir = uieditfield(app.PresentationmodeButtonGroup, 'text');
            app.currentVideoDir.ValueChangedFcn = createCallbackFcn(app, @currentVideoDirValueChanged, true);
            app.currentVideoDir.Editable = 'off';
            app.currentVideoDir.Enable = 'off';
            app.SelectVideoDirButton = uibutton(app.PresentationmodeButtonGroup, 'push');
            app.SelectVideoDirButton.ButtonPushedFcn = createCallbackFcn(app, @SelectVideoDirButtonPushed, true);
            app.SelectVideoDirButton.Enable = 'off';
            app.SelectVideoDirButton.Text = 'Select';
            app.ScoringtypeButtonGroup = uibuttongroup(app.SettingsPanelinSettingsTab);
            app.ScoringtypeButtonGroup.SelectionChangedFcn = createCallbackFcn(app, @ScoringtypeButtonGroupSelectionChanged, true);
            app.ScoringtypeButtonGroup.Title = 'Scoring type';
            app.KeywordBasedButton = uiradiobutton(app.ScoringtypeButtonGroup);
            app.KeywordBasedButton.Text = 'Keyword-based';
            app.WordByWordButton = uiradiobutton(app.ScoringtypeButtonGroup);
            app.WordByWordButton.Text = 'Word-by-word';
            app.WordByWordButton.Value = true;
            app.PlaybackmethodButtonGroup = uibuttongroup(app.SettingsPanelinSettingsTab);
            app.PlaybackmethodButtonGroup.SelectionChangedFcn = createCallbackFcn(app, @playbackMethodChanged, true);
            app.PlaybackmethodButtonGroup.Title = 'Playback method';
            app.ChannelsOutEditFieldLabel = uilabel(app.PlaybackmethodButtonGroup);
            app.ChannelsOutEditFieldLabel.Enable = 'off';
            app.ChannelsOutEditFieldLabel.Text = 'Channel(s) out';
            app.ChannelsOutEditField = uieditfield(app.PlaybackmethodButtonGroup, 'text');
            app.ChannelsOutEditField.ValueChangedFcn = createCallbackFcn(app, @ChannelsOutEditFieldValueChanged, true);
            app.ChannelsOutEditField.Enable = 'off';
            app.ChannelsOutEditField.Tooltip = {'Specify the channel numbers for the output. Single and multiple values are accepted.'};
            app.DriverDropDown = uidropdown(app.PlaybackmethodButtonGroup);
            app.DriverDropDown.Items = {'ASIO', 'DirectSound', 'WASAPI', 'ALSA', 'CoreAudio'};
            app.DriverDropDown.Enable = 'off';
            app.DriverDropDown.Value = 'ASIO';
            app.DriverDropDownLabel = uilabel(app.PlaybackmethodButtonGroup);
            app.DriverDropDownLabel.Enable = 'off';
            app.DriverDropDownLabel.Text = 'Driver';
            app.PlayrecButton = uiradiobutton(app.PlaybackmethodButtonGroup);
            app.PlayrecButton.Enable = 'off';
            app.PlayrecButton.Text = 'Playrec';
            app.AudioToolboxButton = uiradiobutton(app.PlaybackmethodButtonGroup);
            app.AudioToolboxButton.Text = 'Audio Toolbox';
            app.BasicButton = uiradiobutton(app.PlaybackmethodButtonGroup);
            app.BasicButton.Text = 'Basic';
            app.BasicButton.Value = true;
            app.ChannelsInEditFieldLabel = uilabel(app.PlaybackmethodButtonGroup);
            app.ChannelsInEditFieldLabel.Enable = 'off';
            app.ChannelsInEditFieldLabel.Text = 'Channel(s) in';
            app.ChannelsInEditField = uieditfield(app.PlaybackmethodButtonGroup, 'text');
            app.ChannelsInEditField.ValueChangedFcn = createCallbackFcn(app, @ChannelsInEditFieldValueChanged, true);
            app.ChannelsInEditField.Enable = 'off';
            app.ChannelsInEditField.Tooltip = {'Specify input devices, e.g. microphone channel.'};
            app.PlaybackmodeButtonGroup = uibuttongroup(app.SettingsPanelinSettingsTab);
            app.PlaybackmodeButtonGroup.SelectionChangedFcn = createCallbackFcn(app, @PlaybackmodeButtonGroupSelectionChanged, true);
            app.PlaybackmodeButtonGroup.Title = 'Playback mode';
            app.StereoButton = uiradiobutton(app.PlaybackmodeButtonGroup);
            app.StereoButton.Text = 'Stereo';
            app.StereoButton.Value = true;
            app.MonoButton = uiradiobutton(app.PlaybackmodeButtonGroup);
            app.MonoButton.Enable = 'off';
            app.MonoButton.Text = 'Mono';
            app.MultiButton = uiradiobutton(app.PlaybackmodeButtonGroup);
            app.MultiButton.Enable = 'off';
            app.MultiButton.Text = 'Multi';
            app.CalibrationButtonGroup = uibuttongroup(app.SettingsPanelinSettingsTab);
            app.CalibrationButtonGroup.SelectionChangedFcn = createCallbackFcn(app, @CalibrationButtonGroupSelectionChanged, true);
            app.CalibrationButtonGroup.Title = 'Calibration';
            app.dBZLabel = uilabel(app.CalibrationButtonGroup);
            app.dBZLabel.Text = 'dB(Z)';
            app.MeasuredLabel = uilabel(app.CalibrationButtonGroup);
            app.MeasuredLabel.Text = 'Measured';
            app.PlaycalibrationfileButton = uibutton(app.CalibrationButtonGroup, 'state');
            app.PlaycalibrationfileButton.ValueChangedFcn = createCallbackFcn(app, @PlaycalibrationfileButtonPushed, true);
            app.PlaycalibrationfileButton.Text = 'Play calibration file';
            app.FilterEditFieldLabel = uilabel(app.CalibrationButtonGroup);
            app.FilterEditFieldLabel.Text = 'Filter';
            app.FilterEditField = uieditfield(app.CalibrationButtonGroup, 'text');
            app.FilterEditField.ValueChangedFcn = createCallbackFcn(app, @FilterEditFieldValueChanged, true);
            app.FilterEditField.Editable = 'off';
            app.ImportequalizationfilterButton = uibutton(app.CalibrationButtonGroup, 'push');
            app.ImportequalizationfilterButton.ButtonPushedFcn = createCallbackFcn(app, @ImportequalizationfilterButtonPushed, true);
            app.ImportequalizationfilterButton.Text = 'Import equalization filter';
            app.RightMeasuredEditFieldLabel = uilabel(app.CalibrationButtonGroup);
            app.RightMeasuredEditFieldLabel.Text = 'R';
            app.RightMeasuredEditField = uieditfield(app.CalibrationButtonGroup, 'numeric');
            app.RightMeasuredEditField.ValueChangedFcn = createCallbackFcn(app, @RightMeasuredEditFieldValueChanged, true);
            app.RightMeasuredEditField.Value = 95;
            app.LeftMeasuredEditField = uieditfield(app.CalibrationButtonGroup, 'numeric');
            app.LeftMeasuredEditField.ValueChangedFcn = createCallbackFcn(app, @LeftMeasuredEditFieldValueChanged, true);
            app.LeftMeasuredEditField.Value = 95;
            app.LeftMeasuredEditFieldLabel = uilabel(app.CalibrationButtonGroup);
            app.LeftMeasuredEditFieldLabel.Text = 'L';
            app.MaskersettingsButtonGroup = uibuttongroup(app.SettingsPanelinSettingsTab);
            app.MaskersettingsButtonGroup.SelectionChangedFcn = createCallbackFcn(app, @MaskersettingsButtonGroupSelectionChanged, true);
            app.MaskersettingsButtonGroup.Title = 'Masker settings';
            app.ContinuousButton = uiradiobutton(app.MaskersettingsButtonGroup);
            app.ContinuousButton.Enable = 'off';
            app.ContinuousButton.Text = 'Continuous';
            app.IntermittentButton = uiradiobutton(app.MaskersettingsButtonGroup);
            app.IntermittentButton.Text = 'Intermittent';
            app.IntermittentButton.Value = true;
            app.SecLabel = uilabel(app.MaskersettingsButtonGroup);
            app.SecLabel.Text = 'sec';
            app.DurationbeforeEditFieldLabel = uilabel(app.MaskersettingsButtonGroup);
            app.DurationbeforeEditFieldLabel.Text = 'Duration before';
            app.DurationbeforeEditField = uieditfield(app.MaskersettingsButtonGroup, 'numeric');
            app.DurationbeforeEditField.Limits = [0 Inf];
            app.DurationbeforeEditField.ValueChangedFcn = createCallbackFcn(app, @DurationbeforeEditFieldValueChanged, true);
            app.DurationbeforeEditField.Value = 0.5;
            app.SavePresetButton = uibutton(app.SettingsPanelinSettingsTab, 'push');
            app.SavePresetButton.ButtonPushedFcn = createCallbackFcn(app, @SavePresetButtonPushed, true);
            app.SavePresetButton.Text = 'Save preset';
            app.ApplySettingsButton = uibutton(app.SettingsPanelinSettingsTab, 'push');
            app.ApplySettingsButton.ButtonPushedFcn = createCallbackFcn(app, @ApplySettingsButtonPushed, true);
            app.ApplySettingsButton.Text = 'Apply settings';
            app.Panel = uipanel(app.UIFigure);
            app.Panel.Tag = 'SettingsPanel';
            app.AudioSettingsTabs = uitabgroup(app.Panel);
            app.AudioSettingsTabs.SelectionChangedFcn = createCallbackFcn(app, @AudioSettingsTabsSelectionChanged, true);
            app.QuickstartTab = uitab(app.AudioSettingsTabs);
            app.QuickstartTab.Title = 'Quick start';
            app.dBSPLLabel = uilabel(app.QuickstartTab);
            app.dBSPLLabel.Text = 'dB SPL';
            app.dBSPLLabel_2 = uilabel(app.QuickstartTab);
            app.dBSPLLabel_2.Text = 'dB SPL';
            app.TalkerDropDownLabel = uilabel(app.QuickstartTab);
            app.TalkerDropDownLabel.Text = 'Talker';
            app.TalkerDropDown = uidropdown(app.QuickstartTab);
            app.TalkerDropDown.Items = {'Female 1', 'Female 2', 'Male 1', 'Male 2', 'Random talker', 'Mixed talkers'};
            app.TalkerDropDown.ValueChangedFcn = createCallbackFcn(app, @TalkerDropDownValueChanged, true);
            app.TalkerDropDown.Value = 'Female 1';
            app.ListnumberDropDownLabel = uilabel(app.QuickstartTab);
            app.ListnumberDropDownLabel.Text = 'List number';
            app.ListnumberDropDown = uidropdown(app.QuickstartTab);
            app.ListnumberDropDown.Items = {'List 1', 'List 2', 'List 3', 'List 4', 'List 5', 'List 6', 'List 7', 'List 8', 'List 9', 'List 10', 'Random list', 'Mixed list'};
            app.ListnumberDropDown.ValueChangedFcn = createCallbackFcn(app, @ListnumberDropDownValueChanged, true);
            app.ListnumberDropDown.Value = 'List 1';
            app.MaskertypeDropDownLabel = uilabel(app.QuickstartTab);
            app.MaskertypeDropDownLabel.Text = 'Masker type';
            app.MaskertypeDropDown = uidropdown(app.QuickstartTab);
            app.MaskertypeDropDown.Items = {'SSN', 'Unmodulated', 'Modulated', 'Sinusoidal', '4T-Babble'};
            app.MaskertypeDropDown.ValueChangedFcn = createCallbackFcn(app, @MaskertypeDropDownValueChanged, true);
            app.MaskertypeDropDown.Value = 'SSN';
            app.RandomizewithinlistCheckBox = uicheckbox(app.QuickstartTab);
            app.RandomizewithinlistCheckBox.ValueChangedFcn = createCallbackFcn(app, @RandomizewithinlistCheckBoxValueChanged, true);
            app.RandomizewithinlistCheckBox.Text = 'Randomize within list';
            app.TargetlevelLabel = uilabel(app.QuickstartTab);
            app.TargetlevelLabel.Text = 'Target level';
            app.TargetlevelEditField = uieditfield(app.QuickstartTab, 'numeric');
            app.TargetlevelEditField.Limits = [0 Inf];
            app.TargetlevelEditField.ValueChangedFcn = createCallbackFcn(app, @TargetlevelEditFieldValueChanged, true);
            app.TargetlevelEditField.Value = 70;
            app.MaskerlevelEditFieldLabel = uilabel(app.QuickstartTab);
            app.MaskerlevelEditFieldLabel.Text = 'Masker level';
            app.MaskerlevelEditField = uieditfield(app.QuickstartTab, 'numeric');
            app.MaskerlevelEditField.Limits = [0 Inf];
            app.MaskerlevelEditField.ValueChangedFcn = createCallbackFcn(app, @MaskerlevelEditFieldValueChanged, true);
            app.MaskerlevelEditField.Value = 70;
            app.SNRLabel = uilabel(app.QuickstartTab);
            app.SNRLabel.Text = 'SNR';
            app.SNRvalue = uilabel(app.QuickstartTab);
            app.SNRvalue.Text = '0.0';
            app.dBLabel = uilabel(app.QuickstartTab);
            app.dBLabel.Text = 'dB';
            app.AdvancedTab = uitab(app.AudioSettingsTabs);
            app.AdvancedTab.Title = 'Advanced';
            app.UploadButton = uibutton(app.AdvancedTab, 'push');
            app.UploadButton.ButtonPushedFcn = createCallbackFcn(app, @UploadButtonPushed, true);
            app.UploadButton.Text = 'Upload';
            app.CSVfileEditFieldLabel = uilabel(app.AdvancedTab);
            app.CSVfileEditFieldLabel.Text = 'CSV file';
            app.CSVfileEditField = uieditfield(app.AdvancedTab, 'text');
            app.CSVfileEditField.Editable = 'off';
            app.AdvancedOptionInstructionsField = uilabel(app.AdvancedTab);
            app.AdvancedOptionInstructionsField.Text = {'Upload a csv file with the settings for the personalized test in the correct format.'; 'Obs. one CSV file per Subject No.'};
            app.UploadedCSVCheckBox = uicheckbox(app.AdvancedTab);
            app.UploadedCSVCheckBox.Enable = 'off';
            app.UploadedCSVCheckBox.Text = 'CheckUploadedCSV';
            app.StartinglistDropDownLabel = uilabel(app.AdvancedTab);
            app.StartinglistDropDownLabel.Text = 'Starting list';
            app.StartinglistDropDown = uidropdown(app.AdvancedTab);
            app.StartinglistDropDown.Items = {};
            app.StartinglistDropDown.Value = {};
            app.LoadPresetButton = uibutton(app.Panel, 'push');
            app.LoadPresetButton.ButtonPushedFcn = createCallbackFcn(app, @LoadPresetButtonPushed, true);
            app.LoadPresetButton.Text = 'Load preset';
            app.UploadedWorkspaceEditField = uieditfield(app.Panel, 'text');
            app.UploadedWorkspaceEditField.Editable = 'off';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = DAST

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end